classdef SciPhi29_harmonize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FilenameLabel                   matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        InputsequencentEditFieldLabel   matlab.ui.control.Label
        InputsequencentEditField        matlab.ui.control.EditField
        BioBrickcompatibilityLabel      matlab.ui.control.Label
        RFC10CheckBox                   matlab.ui.control.CheckBox
        TypeIIsCheckBox                 matlab.ui.control.CheckBox
        ProgressLampLabel               matlab.ui.control.Label
        ProgressLamp                    matlab.ui.control.Lamp
        HarmonizeButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        OutputsequencentEditFieldLabel  matlab.ui.control.Label
        OutputsequencentEditField       matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: HarmonizeButton
        function Start(app, event)
            cla(app.UIAxes)
            pause(0.1)
            app.ProgressLamp.Color = 'red';
            pause(0.1);
            name  = app.FilenameEditField.Value;
            CDS = app.InputsequencentEditField.Value;
            output = cross_species_harmonize(CDS, app.RFC10CheckBox.Value, app.TypeIIsCheckBox.Value, name);
            [~,~,GC_content]=xlsread([pwd '/GC_content.xlsx']);
            GC_content = cell2mat(GC_content(2:end,1));
            [~,~,Organisms]=xlsread([pwd '/organisms.xlsx']);
            
            app.OutputsequencentEditField.Value = ['\fontsize{16}black {\color{magenta}magenta '...
'\color[rgb]{0 .5 .5}teal \color{red}red} black again'];
            
            bar(app.UIAxes, GC_content);
            set(app.UIAxes, 'XTickLabel',Organisms, 'XTick',1:numel(Organisms))
            hold(app.UIAxes,'on')
            x = get(app.UIAxes, 'xlim');
            plot(app.UIAxes,x,[mean(GC_content) mean(GC_content)], 'k', 'linewidth',2);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,x,[mean(GC_content)*0.90 mean(GC_content)*0.90], 'r', 'linewidth',2);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,x,[mean(GC_content)*1.10 mean(GC_content)*1.10], 'r', 'linewidth',2);
            app.ProgressLamp.Color = 'green';

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FilenameLabel = uilabel(app.UIFigure);
            app.FilenameLabel.Text = 'File name:';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.InputsequencentEditFieldLabel = uilabel(app.UIFigure);
            app.InputsequencentEditFieldLabel.Text = 'Input sequence (nt): ';
            app.InputsequencentEditField = uieditfield(app.UIFigure, 'text');
            app.BioBrickcompatibilityLabel = uilabel(app.UIFigure);
            app.BioBrickcompatibilityLabel.Text = 'BioBrick compatibility:';
            app.RFC10CheckBox = uicheckbox(app.UIFigure);
            app.RFC10CheckBox.Text = 'RFC10';
            app.TypeIIsCheckBox = uicheckbox(app.UIFigure);
            app.TypeIIsCheckBox.Text = 'TypeIIs';
            app.ProgressLampLabel = uilabel(app.UIFigure);
            app.ProgressLampLabel.Text = 'Progress:';
            app.ProgressLamp = uilamp(app.UIFigure);
            app.HarmonizeButton = uibutton(app.UIFigure, 'push');
            app.HarmonizeButton.ButtonPushedFcn = createCallbackFcn(app, @Start, true);
            app.HarmonizeButton.Text = 'Harmonize';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'GC Content')
            xlabel(app.UIAxes, 'TaxID')
            ylabel(app.UIAxes, 'GC Content (%)')
            app.OutputsequencentEditFieldLabel = uilabel(app.UIFigure);
            app.OutputsequencentEditFieldLabel.Text = 'Output sequence (nt): ';
            app.OutputsequencentEditField = uieditfield(app.UIFigure, 'text');
            app.OutputsequencentEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SciPhi29_harmonize

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end